classdef ExerciseCESTv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figExerciseCEST         matlab.ui.Figure
        butExcTimePoints        matlab.ui.control.Button
        butParams               matlab.ui.control.Button
        chkRegister             matlab.ui.control.CheckBox
        butClear                matlab.ui.control.Button
        butExport               matlab.ui.control.Button
        chkFilter               matlab.ui.control.CheckBox
        numROIs                 matlab.ui.control.NumericEditField
        LabelNumericEditField3  matlab.ui.control.Label
        butLoad                 matlab.ui.control.Button
        butSave                 matlab.ui.control.Button
        txtDisp                 matlab.ui.control.EditField
        LabelEditField2         matlab.ui.control.Label
        chkWassr                matlab.ui.control.CheckBox
        ChangeDirDropDown       matlab.ui.control.DropDown
        LabelDropDown           matlab.ui.control.Label
        postExPanel             matlab.ui.container.Panel
        txtCESTPost             matlab.ui.control.EditField
        Label7                  matlab.ui.control.Label
        txtB1Post               matlab.ui.control.EditField
        Label6                  matlab.ui.control.Label
        txtB0Post               matlab.ui.control.EditField
        Label5                  matlab.ui.control.Label
        txtRefPost              matlab.ui.control.EditField
        Label4                  matlab.ui.control.Label
        preExPanel              matlab.ui.container.Panel
        txtRefPre               matlab.ui.control.EditField
        Label9                  matlab.ui.control.Label
        txtB0Pre                matlab.ui.control.EditField
        Label8                  matlab.ui.control.Label
        txtCESTPre              matlab.ui.control.EditField
        Label3                  matlab.ui.control.Label
        txtB1Pre                matlab.ui.control.EditField
        Label2                  matlab.ui.control.Label
        DisplayPanel            matlab.ui.container.Panel
        butCESTmap              matlab.ui.control.Button
        butROIrecover           matlab.ui.control.Button
        butROI                  matlab.ui.control.Button
        butB1map                matlab.ui.control.Button
        butCESTw_im             matlab.ui.control.Button
        butB0map                matlab.ui.control.Button
        butReadCalc             matlab.ui.control.Button
        butChooseROI            matlab.ui.control.Button
        butChangeDir            matlab.ui.control.Button
        txtMainDir              matlab.ui.control.EditField
        LabelEditField          matlab.ui.control.Label
        NormalizationGroup      matlab.ui.container.ButtonGroup
        radMT                   matlab.ui.control.RadioButton
        radM0                   matlab.ui.control.RadioButton
        radNeg                  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        %         mainDir % Main directory where dicoms are
        %         curDir % Current directory from which GUI is run
    end
    
    properties (Access = public)
        mainDir             % Main directory where dicoms are
        curDir              % Current directory from which GUI is run
        subdirs             % Subdirectories
        pre                 % Pre exercise stuff
        post                % Post exercise stuff
        handle              % handles for all figures
        fit                 % Fitting stuff
        params              % from params text file (except mainDir)
    end
    
    methods (Access = private)
        
        function setDisplays(app)
            if isfield(app.subdirs,'Refpre')
                app.txtRefPre.Value = app.subdirs.Refpre;
            end
            if ~app.chkWassr.Value && isfield(app.subdirs,'B0magpre')
                app.txtB0Pre.Value = app.subdirs.B0magpre;
            elseif app.chkWassr.Value && isfield(app.subdirs,'WASSRpre')
                app.txtB0Pre.Value = app.subdirs.WASSRpre;
            end
            if isfield(app.subdirs,'B1pre')
                app.txtB1Pre.Value = app.subdirs.B1pre;
            end
            if isfield(app.subdirs,'CESTpre')
                app.txtCESTPre.Value = app.subdirs.CESTpre;
            end
            if isfield(app.subdirs,'Refpost')
                app.txtRefPost.Value = app.subdirs.Refpost;
            end
            if ~app.chkWassr.Value && isfield(app.subdirs,'B0magpost')
                app.txtB0Post.Value = app.subdirs.B0magpost;
            elseif app.chkWassr.Value && isfield(app.subdirs,'WASSRpost')
                app.txtB0Post.Value = app.subdirs.WASSRpost;
            end
            if isfield(app.subdirs,'B1post')
                app.txtB1Post.Value = app.subdirs.B1post;
            end
            if isfield(app.subdirs,'CESTpost')
                app.txtCESTPost.Value = app.subdirs.CESTpost;
            end
        end
        
        function norm = getNormalization(app)
            if app.radNeg.Value==1        % Negative
                norm = 0;
            elseif app.radM0.Value==1     % M0
                norm = 1;
            else                          % MT
                norm = 2;
            end
        end
        
        function setNormalization(app,norm)
            if norm==0
                app.radNeg.Value = 1;
            elseif norm==1
                app.radM0.Value = 1;
            elseif norm==2
                app.radMT.Value = 1;
            else
                warndlg({'Invalid normalization value...';'Using negative normalization'});
                app.radNeg.Value = 1;
            end
        end
        
        function printROIstats(app,sel_weight,selection,sel_fit,isglo)
            if nargin<5 || isempty(isglo)
                isglo = false;
            end
            if isglo
                nrois = 1;
            else
                nrois = app.fit.nrois;
            end
            if sel_fit==1
                npars = size(app.fit.pars,1);
            elseif sel_fit==2
                npars = size(app.fit.pars_2,1);
            end
            colors = 255*app.params.colors;
            if sel_fit==1
                parnames = app.params.parnames;
            elseif sel_fit==2
                parnames = app.params.parnames_2;
            end
            if isglo
                roinames{1} = 'Global';
            elseif isfield(app.params,'roinames')
                roinames = app.params.roinames;
            else
                roinames = [];
            end
            
            for ii=1:nrois
                colstr = sprintf('rgb(%d,%d,%d)',colors(ii,1),colors(ii,2),colors(ii,3));
                if isempty(roinames)
                    cnames{ii} = coloredText('ROI',colstr);
                else
                    cnames{ii} = coloredText(roinames{ii},colstr);
                end
            end
            h = figure('Name','ROI Fit ','NumberTitle','off');
            h.Position(3:4) = [350,200];
            if app.fit.nthr>1
                if isglo
                    if sel_fit==1
                        t = uitable(h,'Data',cat(1,app.fit.glo.pars(:,sel_weight,selection),app.fit.roipix.postglo(selection),app.fit.roipix.preglo),'ColumnName',cnames,'RowName',parnames);
                    elseif sel_fit==2
                        t = uitable(h,'Data',cat(1,app.fit.glo.pars_2(:,sel_weight,selection),app.fit.roipix.postglo(selection),app.fit.roipix.preglo),'ColumnName',cnames,'RowName',parnames);
                    end
                else
                    if sel_fit==1
                        t = uitable(h,'Data',cat(1,app.fit.pars(:,:,sel_weight,selection),app.fit.roipix.post(selection,:),app.fit.roipix.pre),'ColumnName',cnames,'RowName',parnames);
                    elseif sel_fit==2
                        t = uitable(h,'Data',cat(1,app.fit.pars_2(:,:,sel_weight,selection),app.fit.roipix.post(selection,:),app.fit.roipix.pre),'ColumnName',cnames,'RowName',parnames);
                    end
                end
            else
                if isglo
                    if sel_fit==1
                        t = uitable(h,'Data',cat(1,app.fit.glo.pars(:,sel_weight),app.fit.roipix.postglo,app.fit.roipix.preglo),'ColumnName',cnames,'RowName',parnames);
                    elseif sel_fit==2
                        t = uitable(h,'Data',cat(1,app.fit.glo.pars_2(:,sel_weight),app.fit.roipix.postglo,app.fit.roipix.preglo),'ColumnName',cnames,'RowName',parnames);
                    end
                else
                    if sel_fit==1
                        t = uitable(h,'Data',cat(1,app.fit.pars(:,:,sel_weight),app.fit.roipix.post,app.fit.roipix.pre),'ColumnName',cnames,'RowName',parnames);
                    elseif sel_fit==2
                        t = uitable(h,'Data',cat(1,app.fit.pars_2(:,:,sel_weight),app.fit.roipix.post,app.fit.roipix.pre),'ColumnName',cnames,'RowName',parnames);
                    end
                end
            end
            t.FontWeight = 'bold';
            t.Units = 'normalized';
            t.Position = [0 0 1 1];
            t.Units = 'pixel';
            h.Position(3) = t.Extent(3)*1.1;
            %             h.Position(2) = h.Position(2) + h.Position(4) - t.Extent(4);
            t.Units = 'normalized';
            t.Position = t.Extent;
            t.Position(2) = 1 - t.Position(4);
            
            %             button = questdlg('Save table?','','Yes','No','Yes');
            %             if strcmp(button,'Yes')
            %                 saveas(h,fullfile(app.mainDir,'ResultsTable.png'));
            %             end
        end

        function exportROIstats(app)

            if isfield(app.params,'roinames')
                roinames = app.params.roinames;
            else
                roinames = [];
            end

            for ii=1:app.fit.nrois
                if isempty(roinames)
                    cnames{ii} = ['ROI ' num2str(ii)];
                else
                    cnames{ii} = roinames{ii};
                end
            end

            dat = cat(1,app.fit.pars,app.fit.roipix.post,app.fit.roipix.pre);

            T = table(dat,'VariableNames',cnames,'RowNames',app.params.parnames);
            

        end

        function getParams(app)
            
            % Defaults
            app.params.DEBUG = false;
            app.params.cestppm = 1.8;
            app.params.b0tol = 0.3;
            app.params.b1tol = 0.6;
            app.params.globalroi = 0;
            app.params.roithreshopt = 0;
            app.params.roithreshpercentopt = 0;
            app.params.multiscans = 0;
            app.params.weights = {'1'};
            app.params.mapcolumns = 5;
            app.params.colors = get(groot,'defaultAxesColorOrder');
            app.params.maindir = pwd;
            
            pfiles = dir('*params*.csv');
            
            if length(pfiles)==1
                filename = pfiles.name;
            else
                filename = uigetfile('*params*.csv','Choose Parameter file');
            end
            
            fileid = fopen(filename,'r');
            while 1
                if fileid==-1
                    filename = uigetfile('params*.csv','Choose Parameter file');
                    fileid = fopen(filename,'r');
                else
                    P = textscan(fileid,'%s %q','Delimiter',',');
                    
                    for ii=1:length(P{1})
                        temp = lower(P{1}{ii});
                        if strcmp(temp,'maindir')
                            if isempty(app.txtMainDir.Value) % separate
                                app.mainDir = P{2}{ii};
                            else
                                app.mainDir = app.txtMainDir.Value;
                            end
                        else
                            eval(['app.params.' temp ' = eval(P{2}{ii});']);
                        end
                    end
                    fclose(fileid);
                    break
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             if license('test','image_toolbox')
            %                 app.chkRegister.Enable = 'on';
            %             end
            %             button = questdlg('Keep Diary?','','Yes','No','No');
            %             if strcmp(button,'Yes')
            %                 logfile = sprintf('ExerciseCEST%s.log',datestr(now,'yyyymmddHHMMSS'));
            %                 diary(logfile);
            %             end
            
            addpath('common','-begin')
            
            app.mainDir = pwd;
            app.txtMainDir.Value = app.mainDir;
            app.curDir = pwd;
            
            %             app.params.filename = getParams(app);
            getParams(app);
            
            if isfield(app.params,'roinames')
                app.numROIs.Value = length(app.params.roinames);
            end
            
            app.txtMainDir.Value = app.mainDir;
            app.txtDisp.Value = 'Click Change Dir to begin';
            app.figExerciseCEST.Visible = 'off';
            app.figExerciseCEST.Visible = 'on';
            
            
        end

        % Button pushed function: butChangeDir
        function ChangeDir(app, event)
            temp = uigetdir(app.mainDir);
            if temp
                tempspl = strsplit(temp,filesep);
                files = dir(temp);
                dirFlags = [files.isdir] & ~strcmp({files.name},'.') & ~strcmp({files.name},'..');
                if any(strcmp(tempspl,'DICOM')) || strcmp(tempspl{end},'NON_DICOM_ANONYM_FILES') || sum(dirFlags)<4 % DVD drive requires sorting
                    app.txtDisp.Value = 'Sorting...';
                    temp = standalone_sort(temp,temp,1,0);
                    tempspl = strsplit(temp,filesep);
                    temp = strjoin(tempspl(1:end-1),filesep);
                end
                app.mainDir = temp;
                app.txtMainDir.Value = app.mainDir;
                out = NWexerciseCESTsort(app.mainDir);
                if isfield(app.params,'multiscans') && app.params.multiscans
                    out.CESTprepaths = uigetdir2(app.mainDir,'Choose ALL PRE exercise CEST directories');
                    out.CESTprepaths = NWsortscans(out.CESTprepaths);
                    [~, out.CESTpre] = fileparts(out.CESTprepaths{1});
                    out.CESTpostpaths = uigetdir2(app.mainDir,'Choose ALL POST exercise CEST directories');
                    out.CESTpostpaths = NWsortscans(out.CESTpostpaths);
                    [~, out.CESTpost] = fileparts(out.CESTpostpaths{1});
                end
                app.figExerciseCEST.Visible = 'off';
                app.figExerciseCEST.Visible = 'on';
                if ~isempty(out)
                    app.subdirs = out;
                    setDisplays(app);
                    app.txtDisp.Value = 'Ready';
                else
                    msgbox('No matching folders found. Check your main directory.','Error')
                end
            end
        end

        % Value changed function: ChangeDirDropDown
        function ChangeDirDropDownValueChanged(app, event)
            value = app.ChangeDirDropDown.Value;
            if strcmp(value,'CEST Pre') && isfield(app.params,'multiscans') && app.params.multiscans
                new_dirs = uigetdir2(app.mainDir,'Choose ALL PRE exercise CEST directories');
                app.subdirs.CESTprepaths = NWsortscans(new_dirs);
                [~,app.subdirs.CESTpre] = fileparts(app.subdirs.CESTprepaths{1});
            elseif strcmp(value,'CEST Post') && isfield(app.params,'multiscans') && app.params.multiscans
                new_dirs = uigetdir2(app.mainDir,'Choose ALL POST exercise CEST directories');
                app.subdirs.CESTpostpaths = NWsortscans(new_dirs);
                [~,app.subdirs.CESTpost] = fileparts(app.subdirs.CESTpostpaths{1});
            elseif ~strcmp(value,'')
                new_dir  = uigetdir(app.mainDir);
                if new_dir
                    new_subdir = strsplit(new_dir,filesep);
                    new_subdir = new_subdir{end};
                    if strcmp(value,'Ref Pre')
                        app.subdirs.Refpre = new_subdir;
                    elseif strcmp(value,'B0 Pre') && ~app.chkWassr.Value
                        app.subdirs.B0magpre = new_subdir;
                        if strcmpi(new_subdir(1),'s') && ~isnan(str2double(new_subdir(2:5)))
                            ind =  str2double(new_subdir(2:5));
                            app.subdirs.B0phpre = [new_subdir(1) num2str(ind+1,'%04d') new_subdir(6:end)];
                        else
                            splitname = strsplit(new_subdir,'_');
                            ind = str2double(splitname(end));
                            app.subdirs.B0phpre = [strjoin(splitname(1:end-1),'_') '_' num2str(ind+1,'%04d')];
                        end
                    elseif strcmp(value,'B0 Pre') && app.chkWassr.Value
                        app.subdirs.WASSRpre = new_subdir;
                    elseif strcmp(value,'B1 Pre')
                        app.subdirs.B1pre = new_subdir;
                    elseif strcmp(value,'CEST Pre')
                        app.subdirs.CESTpre = new_subdir;
                    elseif strcmp(value,'Ref Post')
                        app.subdirs.Refpost = new_subdir;
                    elseif strcmp(value,'B0 Post') && ~app.chkWassr.Value
                        app.subdirs.B0magpost = new_subdir;
                        if strcmpi(new_subdir(1),'s') && ~isnan(str2double(new_subdir(2:5)))
                            ind =  str2double(new_subdir(2:5));
                            app.subdirs.B0phpost = [new_subdir(1) num2str(ind+1,'%04d') new_subdir(6:end)];
                        else
                            splitname = strsplit(new_subdir,'_');
                            ind = str2double(splitname(end));
                            app.subdirs.B0phpost = [strjoin(splitname(1:end-1),'_') '_' num2str(ind+1,'%04d')];
                        end
                    elseif strcmp(value,'B0 Post') && app.chkWassr.Value
                        app.subdirs.WASSRpost = new_subdir;
                    elseif strcmp(value,'B1 Post')
                        app.subdirs.B1post = new_subdir;
                    elseif strcmp(value,'CEST Post')
                        app.subdirs.CESTpost = new_subdir;
                    end
                end
                setDisplays(app);
                app.ChangeDirDropDown.Value = '';
            end
        end

        % Callback function
        function ViewSubDirs(app, event)
            warning('this should not have happened')
        end

        % Button pushed function: butB0map
        function butB0mapButtonPushed(app, event)
            try
                
                if isfield(app.pre,'B0map') && isfield(app.post,'B0map')
                    B0pre = app.pre.B0map;
                    B0post = app.post.B0map;
                    roipre = app.pre.roimask;
                    roipost = app.post.roimask;
                    meanB0pre = app.pre.B0mean;
                    meanB0post = app.post.B0mean;
                    
                    h = NWplayim([B0pre.*roipre B0post.*roipost],'parula',true,'B0maps');
                    title(['Pre (mean = ' num2str(meanB0pre) ')      Post (mean = ' num2str(meanB0post) ')'])
                    app.handle.B0 = h;
                else
                    warndlg(' No field maps found. Please CALCULATE first');
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            
        end

        % Button pushed function: butB1map
        function butB1mapButtonPushed(app, event)
            try
                
                if isfield(app.pre,'B1map') && isfield(app.post,'B1map')
                    B1pre = app.pre.B1map;
                    B1post = app.post.B1map;
                    roipre = app.pre.roimask;
                    roipost = app.post.roimask;
                    meanB1pre = app.pre.B1mean;
                    meanB1post = app.post.B1mean;
                    
                    h = NWplayim([B1pre.*roipre B1post.*roipost],'parula',true,'B1maps');
                    title(['Pre (mean = ' num2str(meanB1pre) ')      Post (mean = ' num2str(meanB1post) ')'])
                    app.handle.B1 = h;
                else
                    warndlg(' No transmit B1 maps found. Please CALCULATE first');
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            
        end

        % Button pushed function: butCESTmap
        function butCESTmapButtonPushed(app, event)
            if ~isfield(app.params,'mapcolumns'), app.params.mapcolumns = 5; end
            try
                if isfield(app.pre,'CESTmap') && isfield(app.post,'CESTmap')
                    if ndims(app.pre.CESTmap)==4
                        img = cat(4,app.pre.CESTmap,app.post.CESTmap);
                        img = permute(img,[1 2 4 3]);
                        for ii=1:size(img,4)
                            mont(:,:,ii) = NWmontage(squeeze(img(:,:,:,ii)),[nan app.params.mapcolumns]);
                        end                        
                        h = NWplayim(mont,'jet',true,'CESTmaps');
                    else
                        if isfield(app.fit,'plotmax')
                            h = NWmontim(app.pre.CESTmap,app.post.CESTmap,app.params.mapcolumns,'jet',true,'CESTmaps',[0 0.8*app.fit.plotmax],app.mainDir);
                        else
                            h = NWmontim(app.pre.CESTmap,app.post.CESTmap,app.params.mapcolumns,'jet',true,'CESTmaps',[0 16],app.mainDir);
                        end
                    end
                    app.handle.CESTmap = h;
                else
                    warndlg(' No CEST maps found. Please CALCULATE first');
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butCESTw_im
        function butCESTw_imButtonPushed(app, event)
            try
                
                if isfield(app.pre,'CESTposimages') && isfield(app.post,'CESTposimages')
                    if ndims(app.pre.CESTnegimages)==4
                        si = size(app.pre.CESTnegimages);
                        nimg1 = reshape(permute(app.pre.CESTnegimages,[1 2 4 3]),si(1),si(2),[]);
                        pimg1 = reshape(permute(app.pre.CESTposimages,[1 2 4 3]),si(1),si(2),[]);
                        nimg2 = reshape(permute(app.post.CESTnegimages,[1 2 4 3]),si(1),si(2),[]);
                        pimg2 = reshape(permute(app.post.CESTposimages,[1 2 4 3]),si(1),si(2),[]);
                    else
                        si = size(app.post.CESTnegimages);
                        nimg1 = app.pre.CESTnegimages;
                        pimg1 = app.pre.CESTposimages;
                        nimg2 = app.post.CESTnegimages;
                        pimg2 = app.post.CESTposimages;
                    end
                    neg = cat(3,nimg1,nimg2);
                    sep = zeros(si(1),2*si(2));
                    pos = cat(3,pimg1,pimg2);
                    im = cat(2,neg,pos);
                    im2 = im(:,:,1:size(pimg1,3));
                    im2 = cat(3,im2,sep);
                    im2 = cat(3,im2,im(:,:,size(pimg1,3)+1:end));
                    h = NWplayim(im2,'bone',false,'CESTweighted images');
                    app.handle.CESTw = h;
                else
                    warndlg(' No CEST images found. Please CALCULATE first');
                end

                if isfield(app.params,'DEBUG') && app.params.DEBUG
                    app.pre.zspec_ppm
                    if isfield(app.pre,'zspec')
                        if ndims(app.pre.zspec)==3 % must be 2 spatial + 1 temporal dimension
                            temp1 = cat(3,app.pre.zspec,app.post.zspec);
                            NWinteractiveCEST(temp1,[],[],[]); % plots entire z/temporal dimension
                            temp2 = reshape(temp1,size(temp1,1),size(temp1,2),length(app.pre.zspec_ppm),[]);
                            NWinteractiveCEST3d(temp2,[],[],[],'time course');
                            temp3 = permute(temp2,[1 2 4 3]);
                            NWinteractiveCEST3d(temp3,[],[],[],'z-spectra');
                        end

                    end
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butReadCalc
        function butReadCalcButtonPushed(app, event)
            try
                
                if app.chkFilter.Value
                    if isfield(app.params,'filt')
                        app.params.filtstrength = app.params.filt;
                    else
                        app.params.filtstrength = 1;
                    end
                else
                    app.params.filtstrength = 0;
                end
                
                %% Reference
                app.txtDisp.Value = 'Loading reference images';
                for ii={'pre','post'}
                    if strcmp(ii,'pre')
                        pathname1 = fullfile(app.mainDir,app.subdirs.Refpre);
                    else
                        pathname1 = fullfile(app.mainDir,app.subdirs.Refpost);
                    end
                    
                    if ~exist(pathname1,"dir")
                        if strcmp(ii,'pre')
                            refimage = app.pre.refimage;
                            hdr = app.pre.refhdr;
                            dicomhdr = app.pre.refdicomhdr;
                            refimageb = app.pre.refimageb;
                            mask = app.pre.roimask;
                            biasscale = app.pre.bias;
                        else
                            refimage = app.post.refimage;
                            hdr = app.post.refhdr;
                            dicomhdr = app.post.refdicomhdr;
                            refimageb = app.post.refimageb;
                            mask = app.post.roimask;
                            biasscale = app.post.bias;
                        end
                    elseif strcmp(pathname1,app.mainDir)
                        hdr = []; refimage = []; dicomhdr = []; refimageb = []; mask = []; biasscale = [];
                    else
                        [refimage,hdr,dicomhdr,refimageb,mask,biasscale] = readref(pathname1);
                        %                         mask = ones(size(refimage));
                    end
                    if hdr.nz>1
                        app.params.is3d = true;
                    else
                        app.params.is3d = false;
                    end

                    if app.params.is3d
                        if size(refimage,4)>1
                            refimageb = squeeze(refimageb(:,:,:,1));
                            mask = squeeze(mask(:,:,:,1));
                            warndlg(' Multiple Reference images found...using the first.');
                        end
                        % Remove outer most slices of mask
                        mask(:,:,1) = 0;
                        mask(:,:,end) = 0;
                    else
                        if size(refimage,3)>1
                            %                         refimage = squeeze(refimage(:,:,1));
                            refimageb = squeeze(refimageb(:,:,1));
                            mask = squeeze(mask(:,:,1));
                            warndlg(' Multiple Reference images found...using the first.');
                        end
                    end
                    
                    % Manually exclude regions of fat, etc.
                    nexc = [];
                    if ~app.params.is3d % 2d only
                        tempf = figure; imagesc(refimageb), colormap bone
                        if ~isequal(nexc,0) % only check once if no ROIs to exclude
                            if strcmp(ii,'pre')
                                nexc = inputdlg('Regions to manually exclude','Pre',1,{'0'});
                            else
                                nexc = inputdlg('Regions to manually exclude:','Post',1,{num2str(nexc)});
                            end
                            close(tempf);
                        end
                        if isempty(nexc), nexc = 0; else, nexc = str2num(nexc{:}); end
                        if nexc>0
                            if strcmp(ii,'pre')
                                [excpos,excmask] = NWcreateROITemplate(refimageb,nexc,[],[]);
                            else
                                [~,excmask] = NWcreateROITemplate(refimageb,excpos,[],[]);
                            end
                            excmask = prod(~excmask,3);
                            mask = mask.*excmask;
                        end
                    end
                    
                    
                    if strcmp(ii,'pre')
                        app.pre.refdicomhdr = anonymizeDicomHdr(dicomhdr);
                        app.pre.refhdr = hdr;
                        app.pre.refimage = refimage;
                        app.pre.bias = biasscale;
                        app.pre.refimageb = refimageb;
                        app.pre.roimask = mask;
                    else
                        app.post.refdicomhdr = anonymizeDicomHdr(dicomhdr);
                        app.post.refhdr = hdr;
                        app.post.refimage = refimage;
                        app.post.bias = biasscale;
                        app.post.refimageb = refimageb;
                        app.post.roimask = mask;
                    end
                    
                end
                               
                
                %% B0
                if app.chkWassr.Value
                    app.txtDisp.Value = 'Loading WASSR images';
                    for ii={'pre','post'}
                        if strcmp(ii,'pre')
                            pathname1 = fullfile(app.mainDir,app.subdirs.WASSRpre);
                            roimask = app.pre.roimask;
                        else
                            pathname1 = fullfile(app.mainDir,app.subdirs.WASSRpost);
                            roimask = app.post.roimask;
                        end
                        
                        if ~exist(pathname1,"dir")
                            if strcmp(ii,'pre')
                                B0map = app.pre.B0map;
                                posimages = app.pre.WASSRposimages;
                                negimages = app.pre.WASSRnegimages;
                                hdr = app.pre.WASSRhdr;
                                pars = [];
                            else
                                B0map = app.post.B0map;
                                posimages = app.post.WASSRposimages;
                                negimages = app.post.WASSRnegimages;
                                hdr = app.post.WASSRhdr;
                                pars = [];
                            end
                        elseif strcmp(pathname1,app.mainDir)
                            B0map = zeros(size(roimask)); hdr = []; pars = []; posimages = []; negimages = [];
                        else
                            [B0map,hdr,pars,posimages,negimages] = readwassr(pathname1,1e6,roimask,app.params.filtstrength); % arbitrary large number
                        end
                        
                        if ~isequal(size(roimask),size(B0map))
                            B0map = imresize(B0map,size(roimask));
                            warndlg('B0map was resized')
                        end
                        
                        roimask( abs(B0map) > app.params.b0tol ) = 0;
                        roimask( B0map==0 ) = 0;

                        B0mean = mean(col(B0map(find(roimask(:)))));

                        if strcmp(ii,'pre')
                            app.pre.B0map = B0map;
                            app.pre.WASSRposimages = posimages;
                            app.pre.WASSRnegimages = negimages;
                            app.pre.WASSRhdr = hdr;
                            app.pre.B0mean = B0mean;
                            if ~isempty(pars)
                                app.pre.WASSRppmlist = pars.ppmlist;
                                app.pre.nWASSRppm = length( pars.ppmlist);
                                app.pre.WASSRb1 = pars.b1;
                                app.pre.WASSRpw = pars.pw;
                                app.pre.WASSRpw1 = pars.pw1;
                                app.pre.WASSRdc = pars.dc;
                            end
                            app.pre.roimask = roimask;
                        else
                            app.post.B0map = B0map;
                            app.post.WASSRposimages = posimages;
                            app.post.WASSRnegimages = negimages;
                            app.post.WASSRhdr = hdr;
                            app.post.B0mean = B0mean;
                            if ~isempty(pars)
                                app.post.WASSRppmlist = pars.ppmlist;
                                app.post.nWASSRppm = length( pars.ppmlist);
                                app.post.WASSRb1 = pars.b1;
                                app.post.WASSRpw = pars.pw;
                                app.post.WASSRpw1 = pars.pw1;
                                app.post.WASSRdc = pars.dc;
                            end
                            app.post.roimask = roimask;
                        end
                    end
                else
                    app.txtDisp.Value = 'Loading MultiEcho GRE images';
                    for ii={'pre','post'}
                        if strcmp(ii,'pre')
                            pathname1 = fullfile(app.mainDir,app.subdirs.B0magpre);
                            pathname2 = fullfile(app.mainDir,app.subdirs.B0phpre);
                            roimask = app.pre.roimask;
                        else
                            pathname1 = fullfile(app.mainDir,app.subdirs.B0magpost);
                            pathname2 = fullfile(app.mainDir,app.subdirs.B0phpost);
                            roimask = app.post.roimask;
                        end
                        
                        if ~exist(pathname2,"dir")
                            if strcmp(ii,'pre')
                                B0map = app.pre.B0map;
                                ampl = app.pre.B0GREA;
                                phases = app.pre.B0GREP;
                                hdr = app.pre.B0GREhdr;
                            else
                                B0map = app.post.B0map;
                                ampl = app.post.B0GREA;
                                phases = app.post.B0GREP;
                                hdr = app.post.B0GREhdr;
                            end
                        elseif strcmp(pathname2,app.mainDir)
                            B0map = zeros(size(roimask)); hdr = []; ampl = []; phases = [];
                        else
                            [B0map, hdr, ampl, phases] = readB0gre(pathname1,pathname2,app.params.filtstrength);
                        end
                        
                        roimask( abs(B0map) > app.params.b0tol ) = 0;
                        roimask( B0map==0 ) = 0;
                                                
                        if ~isequal(size(roimask),size(B0map))
                            B0map = imresize(B0map,size(roimask));
                            warndlg('B0map was resized')
                        end                     
                             
                        B0mean = mean(col(B0map(find(roimask(:)))));

                        if strcmp(ii,'pre')
                            app.pre.B0GREA = ampl;
                            app.pre.B0GREP = phases;
                            if isempty(hdr)
                                app.pre.B0GREhdr = [];
                            else
                                app.pre.B0GREhdr = hdr(1);
                            end
                            app.pre.B0map = B0map;
                            app.pre.roimask = roimask;
                            app.pre.B0mean = B0mean;
                        else
                            app.post.B0GREA = ampl;
                            app.post.B0GREP = phases;
                            if isempty(hdr)
                                app.post.B0GREhdr = [];
                            else
                                app.post.B0GREhdr = hdr(1);
                            end
                            app.post.B0map = B0map;
                            app.post.roimask = roimask;
                            app.post.B0mean = B0mean;
                        end
                    end
                    
                end
                
                %% B1
                app.txtDisp.Value = 'Loading B1 images';
                for ii={'pre','post'}
                    if strcmp(ii,'pre')
                        pathname1 = fullfile(app.mainDir,app.subdirs.B1pre);
                        roimask = app.pre.roimask;
                    else
                        pathname1 = fullfile(app.mainDir,app.subdirs.B1post);
                        roimask = app.post.roimask;
                    end
                    
                    if ~exist(pathname1,"dir")
                        if strcmp(ii,'pre')
                            B1map = app.pre.B1map;
                            images = app.pre.B1images;
                            alpha = app.pre.alpha;
                        else
                            B1map = app.post.B1map;
                            images = app.post.B1images;
                            alpha = app.post.alpha;
                        end
                    elseif strcmp(pathname1,app.mainDir)
                        B1map = ones(size(roimask)); hdr = []; alpha = []; images = [];
                    else
                        [B1map,hdr,alpha,images,pathname1] = readB1(pathname1,app.params.filtstrength,roimask);
                    end
                    
                    if ~isequal(size(roimask),size(B1map))
                        B1map = imresize(B1map,size(roimask));
                        warndlg('B1map was resized')
                    end
                    
                    roimask( B1map<1-app.params.b1tol ) = 0;
                    roimask( B1map>1+app.params.b1tol ) = 0;

                    B1mean = mean(col(B1map(find(roimask(:)))));
                    
                    if strcmp(ii,'pre')
                        app.pre.B1map = B1map;
                        app.pre.B1images = images;
                        app.pre.alpha = alpha;
                        app.pre.roimask = roimask;
                        app.pre.B1mean = B1mean;
                        if isempty(hdr)
                            app.pre.B1hdr = [];
                        else
                            app.pre.B1hdr = hdr(1);
                        end
                    else
                        app.post.B1map = B1map;
                        app.post.B1images = images;
                        app.post.alpha = alpha;
                        app.post.roimask = roimask;
                        app.post.B1mean = B1mean;
                        if isempty(hdr)
                            app.post.B1hdr = [];
                        else
                            app.post.B1hdr = hdr(1);
                        end
                    end
                end
                
                %% CEST images
                app.txtDisp.Value = 'Loading CEST-weighted images';
                for ii={'pre','post'}
                    if strcmp(ii,'pre')
                        if isfield(app.params,'multiscans') && app.params.multiscans
                            pathname1 = app.subdirs.CESTprepaths;
                        else
                            pathname1 = fullfile(app.mainDir,app.subdirs.CESTpre);
                        end
                    else
                        if isfield(app.params,'multiscans') && app.params.multiscans
                            pathname1 = app.subdirs.CESTpostpaths;
                        else
                            pathname1 = fullfile(app.mainDir,app.subdirs.CESTpost);
                        end
                    end
                    app.txtDisp.Value = 'Loading CEST images';
                    
                    if ~exist(pathname1,"dir")
                        if strcmp(ii,'pre')
                            posimages = app.pre.CESTposimages;
                            negimages = app.pre.CESTnegimages;
                            hdr = app.pre.CESThdr;
                            pars = app.pre.CESTpars;
                        else
                            posimages = app.post.CESTposimages;
                            negimages = app.post.CESTnegimages;
                            hdr = app.post.CESThdr;
                            pars = app.post.CESTpars;
                        end
                    elseif strcmp(pathname1,app.mainDir)
                        posimages = []; negimages = []; hdr = []; pars = [];
                    else
                        [posimages, negimages, hdr, pars] = readCEST(pathname1,app.params.filtstrength);
                    end

                    if app.params.is3d
                        catDim = 4;
                    else
                        catDim = 3;
                    end

                    zspec = [];
                    for jj=1:pars.reqreps
                        indS = 1 + (jj-1)*length(pars.CESTppmlist);
                        indE = indS + length(pars.CESTppmlist) - 1;
                        if app.params.is3d
                            nimgSub = negimages(:,:,:,indS:indE);
                            pimgSub = posimages(:,:,:,indS:indE);
                        else
                            nimgSub = negimages(:,:,indS:indE);
                            pimgSub = posimages(:,:,indS:indE);
                        end
                        zspec = cat(catDim,zspec,flip(nimgSub,catDim),pimgSub);
                    end

                    ppmlist = cat(2,-1*flip(pars.CESTppmlist,2),pars.CESTppmlist);

                    if ~isfield(app.params,'cestppm') 
                        pars.CESTppm = mean(pars.CESTppmlist);
                    else
                        pars.CESTppm = app.params.cestppm;
                    end
                                       
                    if strcmp(ii,'pre')
                        app.pre.reqreps = pars.reqreps;
                        app.pre.mocoreps = pars.mocoreps;
                        app.pre.CESTppmlist = pars.CESTppmlist;
                        app.pre.CESTposimages = posimages;
                        app.pre.CESTnegimages = negimages;
                        app.pre.zspec = zspec;
                        app.pre.zspec_ppm = ppmlist;
                        app.pre.CESThdr = hdr(1);
                        app.pre.CESTpars = pars;
                    else
                        app.post.reqreps = pars.reqreps;
                        app.post.mocoreps = pars.mocoreps;
                        app.post.CESTppmlist = pars.CESTppmlist;
                        app.post.CESTposimages = posimages;
                        app.post.CESTnegimages = negimages;
                        app.post.zspec = zspec;
                        app.post.zspec_ppm = ppmlist;
                        app.post.CESThdr = hdr(1);
                        app.post.CESTpars = pars;
                    end
                end
                
                % Parameter Checking
                if max(abs(app.pre.CESTppmlist-app.params.cestppm)) < app.params.b0tol-1e-6 || max(abs(app.post.CESTppmlist-app.params.cestppm)) < app.params.b0tol-1e-6
                    warndlg('B0 tolerance is too large for the acquired offsets.')
                end
                
                %% Corrected CEST
                app.txtDisp.Value = 'Calculating CEST maps';
                for ii={'pre','post'}
                    if strcmp(ii,'pre')
                        roimask = app.pre.roimask;
                        posimages = app.pre.CESTposimages;
                        negimages = app.pre.CESTnegimages;
                        ppmlist = app.pre.CESTppmlist;
                        reqreps = app.pre.reqreps;
                        mocoreps = app.pre.mocoreps;
                        B1map = app.pre.B1map;
                        B0map = app.pre.B0map;
                        refimage = app.pre.refimage;
                        CESTpars = app.pre.CESTpars;
                    else
                        roimask = app.post.roimask;
                        posimages = app.post.CESTposimages;
                        negimages = app.post.CESTnegimages;
                        ppmlist = app.post.CESTppmlist;
                        reqreps = app.post.reqreps;
                        mocoreps = app.post.mocoreps;
                        B1map = app.post.B1map;
                        B0map = app.post.B0map;
                        refimage = app.post.refimage;
                        CESTpars = app.post.CESTpars;
                    end
                    
                    % hard-coded for Exercise CEST
                    opt = 2;
                    poly_deg = 2;

                    imgs.CESTp = posimages;
                    imgs.CESTn = negimages;
                    imgs.roimask = roimask;
                    imgs.B0map = B0map;
                    imgs.B1map = B1map;
                    imgs = getCESTFromScans(CESTpars,imgs,opt,poly_deg);
                    % [CESTmap,B0corrp,B0corrn] = getCESTFromScans(CESTpars,posimages,negimages,B0map,B1map,roimask,opt,poly_deg);

                    if strcmp(ii,'pre')
                        app.pre.CESTmap = imgs.CESTmap;
                        app.pre.B0corrp = imgs.B0CorrP;
                        app.pre.B0corrn = imgs.B0CorrN;
                    else
                        app.post.CESTmap = imgs.CESTmap;
                        app.post.B0corrp = imgs.B0CorrP;
                        app.post.B0corrn = imgs.B0CorrN;
                    end

                end
                
                app.txtDisp.Value = 'DONE';
                
            catch errorObj
                fclose all
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butChooseROI
        function butChooseROIButtonPushed(app, event)
            try
                
                %                 refpre = app.pre.refimageb .* app.pre.roimask;
                %                 refpost = app.post.refimageb .* app.post.roimask;
                refpre = app.pre.refimageb;
                refpost = app.post.refimageb;
                
                if ~isfield(app.params,'roinames')
                    app.params.roinames = [];
                end

                if ~isfield(app.fit,'excludedTimePoints')
                    app.fit.excludedTimePoints = [];
                end
                
                %% Determine ROIs from post exercise image
                app.txtDisp.Value = 'Getting ROIs...';
                if isfield(app.params,'globalroi')
                    if (islogical(app.params.globalroi) && app.params.globalroi==true) || (ischar(app.params.globalroi) && strcmp(app.params.globalroi,'true'))
                        if isfield(app.params,'gloroiexc')
                            if isfield(app.post,'gloroipos')
                                [postposglo,postmasksglo] = NWcreateROITemplate(refpost,app.post.gloroipos,[],app.params.gloroiexc);
                            else
                                [postposglo,postmasksglo] = NWcreateROITemplate(refpost,length(app.params.gloroiexc),[],app.params.gloroiexc);
                            end
                            gloroipost = app.post.roimask - logical(sum(postmasksglo,3));
                            gloroipost(gloroipost<0) = 0;
                            gloroipost = logical(gloroipost);
                        else
                            postposglo = [];
                            gloroipost = app.post.roimask;
                        end
                    end
                end
                % Post exercise ROIs
                if isfield(app.post,'roipos') && isequal(length(app.post.roipos),app.numROIs.Value)
                    [postpos,postmasks] = NWcreateROITemplate(refpost,app.post.roipos,[],app.params.roinames);
                else
                    [postpos,postmasks] = NWcreateROITemplate(refpost,app.numROIs.Value,[],app.params.roinames);
                end
                
                if isfield(app.params,'globalroi')
                    if (islogical(app.params.globalroi) && app.params.globalroi==true) || (ischar(app.params.globalroi) && strcmp(app.params.globalroi,'true'))
                        if isfield(app.params,'gloroiexc')
                            if isfield(app.pre,'gloroipos')
                                [preposglo,premasksglo] = NWcreateROITemplate(refpre,app.pre.gloroipos,[],app.params.gloroiexc);
                            else
                                [preposglo,premasksglo] = NWcreateROITemplate(refpre,postposglo,[],app.params.gloroiexc);
                            end
                            gloroipre = app.pre.roimask - logical(sum(premasksglo,3));
                            gloroipre(gloroipre<0) = 0;
                            gloroipre = logical(gloroipre);
                        else
                            preposglo = [];
                            gloroipre = app.pre.roimask;
                        end
                    end
                end
                % Pre exercise ROIs
                if isfield(app.pre,'roipos') && isequal(length(app.pre.roipos),app.numROIs.Value)
                    [prepos,premasks] = NWcreateROITemplate(refpre,app.pre.roipos,[],app.params.roinames);
                else
                    [prepos,premasks] = NWcreateROITemplate(refpre,postpos,[],app.params.roinames);
                end
                
                sumroispre = squeeze(sum(sum(premasks,2),1));
                sumroispost = squeeze(sum(sum(postmasks,2),1));
                percentDiff = 100*abs(sumroispre-sumroispost)./sumroispost;
                if max(percentDiff(:))>20 % is 20% a reasonable amount?
                    hwarn = warndlg('Large Difference in ROI sizes pre and post exercise','Warning');
                    uiwait(hwarn)
                end
                
                app.pre.rois_full = premasks; % full rois not restricted by b0/b1 inhomogeneity
                app.post.rois_full = postmasks;
                for cc=1:size(postmasks,3)
                    premasks(:,:,cc) = premasks(:,:,cc) .* app.pre.roimask;
                    postmasks(:,:,cc) = postmasks(:,:,cc) .* app.post.roimask;
                end
                app.pre.rois = premasks;
                app.post.rois = postmasks;
                app.fit.roipix.pre = sumroispre(:)'; % row vector
                app.fit.roipix.post = sumroispost(:)';
                app.pre.roipos = prepos;
                app.post.roipos = postpos;
                if exist('gloroipre','var')
                    app.pre.gloroi = gloroipre;
                    app.post.gloroi = gloroipost;
                    app.pre.gloroipos = preposglo;
                    app.post.gloroipos = postposglo;
                    app.fit.roipix.preglo = sum(gloroipre(:));
                    app.fit.roipix.postglo = sum(gloroipost(:));
                end
                
                
                %% Fit recovery curves
                app.txtDisp.Value = 'Fitting recovery curves...';
                
                
                if ~isfield(app.params,'myfun')
                    myfun = [];
                    par0 = [];
                    parnames = [];
                    skip_fit = true;
                    app.fit.skip_fit = true;
                else
                    myfun = app.params.myfun;
                    par0 = app.params.par0;
                    skip_fit = false;
                    app.fit.skip_fit = false;
                    
                    if isfield(app.params,'parnames')
                        parnames = app.params.parnames;
                    else
                        switch length(par0)
                            case 4
                                parnames = {'% Increase','Recovery constant','POST baseline','PRE baseline'};
                            case 5
                                parnames = {'% Increase','Recovery constant','POST baseline','PRE slope','PRE baseline'};
                            otherwise
                                parnames = [];
                        end
                    end
                    
                    npars = length(par0);
                    if isequal(npars,length(parnames))
                        parnames{npars+1} = 'Voxels Post';
                        parnames{npars+2} = 'Voxels Pre';
                    end
                    app.params.parnames = parnames;
                    
                end
                
                cestpre = app.pre.CESTmap;
                cestpost = app.post.CESTmap;
                cest = cat(3,cestpre,cestpost);
                npre = size(cestpre,3);
                npost = size(cestpost,3);
                nx = size(cestpre,1);
                ny = size(cestpre,2);
                
                roipre = app.pre.rois;
                roipost = app.post.rois;
                nrois = size(roipre,3);

                includedTimePoints = 1:npre+npost;
                if ~isempty(app.fit.excludedTimePoints)
                    includedTimePoints(app.fit.excludedTimePoints) = [];
                end
                
                
                if isfield(app.post.CESTpars,'shotTRms') && ~isempty(app.post.CESTpars.shotTRms) % preferred moving forward
                    scandurpre = 1e-3 * app.pre.CESTpars.shotTRms * 2 * length(app.pre.CESTppmlist);
                    scandurpost = 1e-3 * app.post.CESTpars.shotTRms * 2 * length(app.post.CESTppmlist);
                elseif strfind(app.pre.refhdr.swversion,'VD13A')
                    scandurpre = 1e-3*app.pre.CESThdr.WIPlong(7)*2*length(app.pre.CESTppmlist); % 2 for +,- frequencies
                    scandurpost = 1e-3*app.post.CESThdr.WIPlong(7)*2*length(app.post.CESTppmlist);
                elseif strfind(app.pre.refhdr.SeqName,'prepCV')
                    scandurpre = 1e-6*app.pre.CESThdr.TR*2*length(app.pre.CESTppmlist);
                    scandurpost = 1e-6*app.post.CESThdr.TR*2*length(app.post.CESTppmlist);
                elseif ~isempty(strfind(app.pre.refhdr.swversion,'VD13D')) || ~isempty(strfind(app.pre.refhdr.swversion,'VE11'))
                    scandurpre = 1e-3*app.pre.CESThdr.WIPlong(8)*2*length(app.pre.CESTppmlist); % 2 for +,- frequencies
                    scandurpost = 1e-3*app.post.CESThdr.WIPlong(8)*2*length(app.post.CESTppmlist);
                elseif strfind(app.pre.refhdr.swversion,'VB17A')
                    scandurpre = 1e-3*app.pre.CESThdr.WIPlong(7)*2*length(app.pre.CESTppmlist);
                    scandurpost = 1e-3*app.post.CESThdr.WIPlong(7)*2*length(app.post.CESTppmlist);
                else
                    scandurpre = input('Enter PRE scan duration (s): ');
                    scandurpost = input('Enter POST scan duration (s): ');
                end
                
                timepoints = [-scandurpre*(npre-0.5):scandurpre:0 scandurpost*((1:npost)-0.5)];
                
                %             button = questdlg('Select weighting for recovery fit','Weight','constant','1/N','SNR','SNR');
                %                 button = questdlg4NW('Select weighting for recovery fit','Weight','1','1/N','S','S/N','S/N');
                
                nthr_abs = 0; nthr_per = 0;
                roipostth_abs = []; roipostth_per = [];
                if isfield(app.params,'roithresh')
                    nthr_abs = length(app.params.roithresh);
                    roipostth_abs = zeros([size(refpre), nrois, nthr_abs]);
                    for kk=1:nthr_abs
                        for ii = 1:nrois
                            if ~isfield(app.params,'roithreshopt') || app.params.roithreshopt==0
                                cestdiffpost = cest(:,:,npre+1)-cest(:,:,end); % post compared to recovery point-by-point
                            else
                                ind = find(roipost(:,:,ii));
                                [ind1,ind2] = ind2sub([nx,ny],ind);
                                cestdiffpost = cest(:,:,npre+1)-mean(col(cest(ind1,ind2,1:npre))); % post compared to mean pre ROI
                            end
                            roipostth_abs(:,:,ii,kk) = (cestdiffpost>app.params.roithresh(kk)) .* logical(roipost(:,:,ii));
                        end
                    end
                end
                if isfield(app.params,'roithreshpercent')
                    nthr_per = length(app.params.roithreshpercent);
                    roipostth_per = zeros([size(refpre), nrois, nthr_per]);
                    for kk=1:nthr_per
                        for ii=1:nrois
                            if ~isfield(app.params,'roithreshpercentopt') || app.params.roithreshpercentopt==0
                                cestdiffpost = 100*(cest(:,:,npre+1)-cest(:,:,end))./cest(:,:,end);
                            else
                                ind = find(roipost(:,:,ii));
                                [ind1,ind2] = ind2sub([nx,ny],ind);
                                cestdiffpost = 100*(cest(:,:,npre+1)-mean(col(cest(ind1,ind2,1:npre))))/mean(col(cest(ind1,ind2,1:npre)));
                            end
                            roipostth_per(:,:,ii,kk) = (cestdiffpost>app.params.roithreshpercent(kk)) .* logical(roipost(:,:,ii));
                        end
                    end
                end
                nthr = max(nthr_abs+nthr_per,1);
                app.fit.nthr = nthr;
                app.fit.nthr_abs = nthr_abs;
                app.fit.nthr_per = nthr_per;
                roipostth = cat(4,roipostth_abs,roipostth_per);
                
                weighting = app.params.weights;
                nweights = length(weighting);
                means = ones(nrois,npre+npost,nthr); stds = ones(nrois,npre+npost,nthr);
                a = zeros(length(par0),nrois,nweights,nthr); r = zeros(npre+npost,nrois,nweights,nthr); J = zeros(npre+npost,length(par0),nrois,nweights,nthr);
                cov = zeros(length(par0),length(par0),nrois,nweights,nthr); mse = zeros(1,nrois,nweights,nthr);
                fitdata = zeros(length(timepoints),nrois,nweights,nthr);
                ci95 = zeros(length(par0),2,nrois,nweights,nthr); ci90 = ci95;
                app.fit.nfit = 1;
                if isfield(app.params,'myfun_2') && isfield(app.params,'par0_2') && isfield(app.params,'parnames_2')
                    app.fit.nfit = 2;
                    par0_2 = app.params.par0_2;
                    myfun2 = app.params.myfun_2;
                    parnames2 = app.params.parnames_2;
                    a2 = zeros(length(par0_2),nrois,nweights,nthr); r2 = zeros(npre+npost,nrois,nweights,nthr); J2 = zeros(npre+npost,length(par0_2),nrois,nweights,nthr);
                    cov2 = zeros(length(par0_2),length(par0_2),nrois,nweights,nthr); mse2 = zeros(1,nrois,nweights,nthr);
                    fitdata2 = zeros(length(timepoints),nrois,nweights,nthr);
                    ci95_2 = zeros(length(par0_2),2,nrois,nweights,nthr); ci90_2 = ci95_2;
                end
                baseline = zeros(nrois,nthr); firstpost = zeros(nrois,nthr); Tt50sp = zeros(nrois,nthr); Tt50lin = zeros(nrois,nthr);
                warning('off','stats:nlinfit:IllConditionedJacobian');
                for kk=1:nthr % number of thresholds
                    for ii=1:nrois % number of rois
                        for jj=1:npre+npost % number of timepoints
                            cestpoint = cest(:,:,jj);
                            if jj<npre+1
                                cestroi = cestpoint(roipre(:,:,ii)>0);
                            else
                                cestroi = cestpoint(roipost(:,:,ii)>0);
                                if isfield(app.params,'roithresh') || isfield(app.params,'roithreshpercent')
                                    cestroith = cestpoint(roipostth(:,:,ii,kk)>0);
                                else
                                    cestroith = [];
                                end
                            end
                            if jj>=npre+1 && ~isempty(cestroith)
                                means(ii,jj,kk) = mean(cestroith);
                                stds(ii,jj,kk) = std(cestroith);
                            elseif isempty(cestroi)
                                means(ii,jj,kk) = 0;
                                stds(ii,jj,kk) = 0;
                            else
                                means(ii,jj,kk) = mean(cestroi);
                                stds(ii,jj,kk) = std(cestroi);
                            end
                        end
                        if ~skip_fit
                            for zz=1:nweights
                                switch weighting{zz}%button
                                    case '1'
                                        weights = ones(size(means));
                                    case '1/N'
                                        weights = 1./stds;
                                    case 'S'
                                        weights = means;
                                    case 'S/N'
                                        weights = means./stds;
                                    otherwise
                                        weights = ones(size(means));
                                end
                                weights = abs(weights);
                                for ff=1:2
                                    try
                                        tinc = timepoints(includedTimePoints);
                                        sig = means(ii,includedTimePoints,kk);
                                        if ff==1
                                            [a(:,ii,zz,kk),r(:,ii,zz,kk),J(:,:,ii,zz,kk),cov(:,:,ii,zz,kk),mse(:,ii,zz,kk)] = nlinfit(timepoints(includedTimePoints),means(ii,includedTimePoints,kk),myfun,par0,'weights',weights(ii,:,kk));
                                            ci95(:,:,ii,zz,kk) = nlparci(a(:,ii,zz,kk),r(:,ii,zz,kk),'covar',cov(:,:,ii,zz,kk),'alpha',0.05);
                                            ci90(:,:,ii,zz,kk) = nlparci(a(:,ii,zz,kk),r(:,ii,zz,kk),'covar',cov(:,:,ii,zz,kk),'alpha',0.1);
                                            fitdata(:,ii,zz,kk) = myfun(a(:,ii,zz,kk),timepoints);
                                        elseif ff==2
                                            % NW temp
                                            if isfield(app.params,'myfun_2') && isfield(app.params,'par0_2') && isfield(app.params,'parnames_2')
                                                [a2(:,ii,zz,kk),r2(:,ii,zz,kk),J2(:,:,ii,zz,kk),cov2(:,:,ii,zz,kk),mse2(:,ii,zz,kk)] = nlinfit(timepoints(includedTimePoints),means(ii,includedTimePoints,kk),myfun2,par0_2,'weights',weights(ii,:,kk));
                                                ci95_2(:,:,ii,zz,kk) = nlparci(a2(:,ii,zz,kk),r2(:,ii,zz,kk),'covar',cov2(:,:,ii,zz,kk),'alpha',0.05);
                                                ci90_2(:,:,ii,zz,kk) = nlparci(a2(:,ii,zz,kk),r2(:,ii,zz,kk),'covar',cov2(:,:,ii,zz,kk),'alpha',0.1);
                                                fitdata2(:,ii,zz,kk) = myfun2(a2(:,ii,zz,kk),timepoints);
                                            end
                                        end
                                    catch errorObj
                                        %                                         if length(findall(0,'Type','figure','Name','Error'))==0
                                        %                                             errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
                                        %                                         end
                                        fclose all
                                        disp(['fit error with ROI' num2str(ii) ', threshold ' num2str(kk) ', weight ' num2str(zz) ', function ' num2str(ff)]);
                                    end
                                end
                            end
                        end
                        
                        % Simple fit proxy variables
                        means_vec = squeeze(means(ii,:,kk));
                        timepoints_interp = linspace(timepoints(npre+1),timepoints(end),50);
                        means_sp = interp1(timepoints(npre+1:end),means_vec(npre+1:end),timepoints_interp,'spline');
                        means_lin = interp1(timepoints(npre+1:end),means_vec(npre+1:end),timepoints_interp,'linear');
                        if mean(means_vec(1:npre))<0.1 || isnan(mean(means_vec(1:npre))) % no pre exercise
                            baseline(ii,kk) = mean(means_vec(end-1:end)); % last two scans
                        else
                            baseline(ii,kk) = mean(means_vec(1:npre));
                        end
                        firstpost(ii,kk) = means_vec(npre+1);
                        halfrecov = (firstpost(ii,kk)-baseline(ii,kk))/2 + baseline(ii,kk);
                        [~,indsp] = min(abs(means_sp-halfrecov));
                        [~,indlin] = min(abs(means_lin-halfrecov));
                        Tt50sp(ii,kk) = timepoints_interp(indsp);
                        Tt50lin(ii,kk) = timepoints_interp(indlin);
                    end
                end
                warning('on','stats:nlinfit:IllConditionedJacobian');
                
                % Data-based slopes
                slope2 = ( means(:,npre+2,:)-means(:,npre+1,:) ) ./ ( timepoints(npre+2)-timepoints(npre+1) ); % first 2 points only, no regression
                
                if npost>=3
                    p3 = NWpolyfitim(1,timepoints(npre+1:npre+3),permute(means(:,npre+1:npre+3,:),[1 3 2]));
                    slope3 = squeeze(p3(:,:,1));
                else
                    p3 = [];
                    slope3 = [];
                end
                if npost>=4
                    p4 = NWpolyfitim(1,timepoints(npre+1:npre+4),permute(means(:,npre+1:npre+4,:),[1 3 2]));
                    slope4 = squeeze(p4(:,:,1));
                else
                    p4 = [];
                    slope4 = [];
                end
                if npost>=5
                    p5 = NWpolyfitim(1,timepoints(npre+1:npre+5),permute(means(:,npre+1:npre+5,:),[1 3 2]));
                    slope5 = squeeze(p5(:,:,1));
                else
                    p5 = [];
                    slope5 = [];
                end
                
                maxsig = means+stds;
                maxsig = max(maxsig(:));
                app.fit.nrois = nrois;
                if ~skip_fit
                    app.fit.pars = a;
                    app.fit.res = r;
                    app.fit.J = J;
                    app.fit.cov = cov;
                    app.fit.mse = mse;
                    app.fit.confidence_intervals95 = ci95;
                    app.fit.confidence_intervals90 = ci90;
                    app.fit.fitdata = fitdata;
                    app.fit.nweights = nweights;
                    % NW temp
                    if isfield(app.params,'myfun_2') && isfield(app.params,'par0_2') && isfield(app.params,'parnames_2')
                        app.params.parnames_2{length(app.params.par0_2)+1} = 'Voxels Post';
                        app.params.parnames_2{length(app.params.par0_2)+2} = 'Voxels Pre';
                        app.fit.pars_2 = a2;
                        app.fit.res_2 = r2;
                        app.fit.J_2 = J2;
                        app.fit.cov_2 = cov2;
                        app.fit.mse_2 = mse2;
                        app.fit.confidence_intervals95_2 = ci95_2;
                        app.fit.confidence_intervals90_2 = ci90_2;
                        app.fit.fitdata_2 = fitdata2;
                    end
                end
                app.fit.means = means;
                app.fit.stds = stds;
                app.fit.timepoints = timepoints;
                app.fit.plotmax = maxsig;
                if isfield(app.params,'roithresh')
                    app.post.roisth = roipostth;
                    for kk=1:nthr
                        for ii=1:nrois
                            temp = roipostth(:,:,ii,kk);
                            app.fit.roipix.post(kk,ii) = sum(temp(:));
                        end
                    end
                end
                app.fit.simplepars.baseline = baseline;
                app.fit.simplepars.firstpost = firstpost;
                app.fit.simplepars.increase = firstpost-baseline;
                app.fit.simplepars.Tt50.spline = Tt50sp;
                app.fit.simplepars.Tt50.linear = Tt50lin;
                app.fit.simplepars.slope2 = slope2;
                app.fit.simplepars.slope3 = slope3;
                app.fit.simplepars.slope4 = slope4;
                app.fit.simplepars.slope5 = slope5;
                
                
                %% Global ROI if applicable
                if exist('gloroipre','var')
                    if isfield(app.params,'roithresh')
                        roipostth_abs = zeros([size(app.post.roimask),nthr_abs]);
                        for kk=1:nthr_abs
                            if ~isfield(app.params,'roithreshopt') || app.params.roithreshopt==0
                                cestdiffpost = cest(:,:,npre+1)-cest(:,:,end); % post compared to recovery
                            else
                                ind = find(gloroipost);
                                [ind1,ind2] = ind2sub([nx,ny],ind);
                                cestdiffpost = cest(:,:,npre+1)-mean(col(cest(ind1,ind2,1:npre))); % post compared to mean pre
                            end
                            roipostth_abs(:,:,kk) = logical(cestdiffpost>app.params.roithresh(kk)) .* logical(gloroipost);
                        end
                    end
                    if isfield(app.params,'roithreshpercent')
                        roipostth_per = zeros([size(app.post.roimask),nthr_per]);
                        for kk=1:nthr_per
                            if ~isfield(app.params,'roithreshpercentopt') || app.params.roithreshpercentopt==0
                                cestdiffpost = 100*(cest(:,:,npre+1)-cest(:,:,end))./cest(:,:,end); % post compared to recovery
                            else
                                ind = find(gloroipost);
                                [ind1,ind2] = ind2sub([nx,ny],ind);
                                cestdiffpost = 100*(cest(:,:,npre+1)-mean(col(cest(ind1,ind2,1:npre))))/mean(col(cest(ind1,ind2,1:npre))); % post compared to mean pre
                            end
                            roipostth_per(:,:,kk) = logical(cestdiffpost>app.params.roithreshpercent(kk)) .* logical(gloroipost);
                        end
                    end
                    roipostth = cat(3,roipostth_abs,roipostth_per);
                    
                    means = ones(npre+npost,nthr); stds = ones(npre+npost,nthr);
                    a = zeros(length(par0),nweights,nthr); r = zeros(npre+npost,nweights,nthr); J = zeros(npre+npost,length(par0),nweights,nthr);
                    cov = zeros(length(par0),length(par0),nweights,nthr); mse = zeros(1,nweights,nthr);
                    fitdata = zeros(length(timepoints),nweights,nthr);
                    ci95 = zeros(length(par0),2,nweights,nthr); ci90 = ci95;
                    if isfield(app.params,'myfun_2') && isfield(app.params,'par0_2') && isfield(app.params,'parnames_2')
                        a2 = zeros(length(par0_2),nweights,nthr); r2 = zeros(npre+npost,nweights,nthr); J2 = zeros(npre+npost,length(par0_2),nweights,nthr);
                        cov2 = zeros(length(par0_2),length(par0_2),nweights,nthr); mse2 = zeros(1,nweights,nthr);
                        fitdata2 = zeros(length(timepoints),nweights,nthr);
                        ci95_2 = zeros(length(par0_2),2,nweights,nthr); ci90_2 = ci95_2;
                    end
                    baseline = zeros(1,nthr); firstpost = zeros(1,nthr); Tt50sp = zeros(1,nthr); Tt50lin = zeros(1,nthr);
                    warning('off','stats:nlinfit:IllConditionedJacobian');
                    for kk=1:nthr
                        for jj=1:npre+npost
                            cestpoint = cest(:,:,jj);
                            if jj<npre+1
                                cestroi = cestpoint(gloroipre>0);
                            else
                                cestroi = cestpoint(gloroipost>0);
                                if isfield(app.params,'roithresh') || isfield(app.params,'roithreshpercent')
                                    cestroith = cestpoint(roipostth(:,:,kk)>0);
                                else
                                    cestroith = [];
                                end
                            end
                            if jj>=npre+1 && ~isempty(cestroith)
                                means(jj,kk) = mean(cestroith);
                                stds(jj,kk) = std(cestroith);
                            else
                                means(jj,kk) = mean(cestroi);
                                stds(jj,kk) = std(cestroi);
                            end
                        end
                        if ~skip_fit
                            for zz=1:nweights
                                switch zz%button
                                    case 1%'1'
                                        weights = ones(size(means));
                                    case 2%'1/N'
                                        weights = 1./stds;
                                    case 3%'S'
                                        weights = means;
                                    case 4%'S/N'
                                        weights = means./stds;
                                end
                                weights = abs(weights);
                                for ff=1:2
                                    try
                                        if ff==1
                                            [a(:,zz,kk),r(:,zz,kk),J(:,:,zz,kk),cov(:,:,zz,kk),mse(:,zz,kk)] = nlinfit(timepoints,means(:,kk).',myfun,par0,'weights',weights(:,kk).');
                                            ci95(:,:,zz,kk) = nlparci(a(:,zz,kk),r(:,zz,kk),'covar',cov(:,:,zz,kk),'alpha',0.05);
                                            ci90(:,:,zz,kk) = nlparci(a(:,zz,kk),r(:,zz,kk),'covar',cov(:,:,zz,kk),'alpha',0.1);
                                            fitdata(:,zz,kk) = myfun(a(:,zz,kk),timepoints);
                                        elseif ff==2% NW temp
                                            if isfield(app.params,'myfun_2') && isfield(app.params,'par0_2') && isfield(app.params,'parnames_2')
                                                [a2(:,zz,kk),r2(:,zz,kk),J2(:,:,zz,kk),cov2(:,:,zz,kk),mse2(:,zz,kk)] = nlinfit(timepoints,means(:,kk).',myfun2,par0_2,'weights',weights(:,kk).');
                                                ci95_2(:,:,zz,kk) = nlparci(a2(:,zz,kk),r2(:,zz,kk),'covar',cov2(:,:,zz,kk),'alpha',0.05);
                                                ci90_2(:,:,zz,kk) = nlparci(a2(:,zz,kk),r2(:,zz,kk),'covar',cov2(:,:,zz,kk),'alpha',0.1);
                                                fitdata2(:,zz,kk) = myfun2(a2(:,zz,kk),timepoints);
                                            end
                                        end
                                    catch errorObj
                                        fclose all
                                        if length(findall(0,'Type','figure','Name','Error'))==0
                                            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
                                        end
                                        
                                        disp(['fit error with Global, threshold ' num2str(kk) ', weight ' num2str(zz) ', function ' num2str(ff)]);
                                        
                                    end
                                end
                            end
                        end
                        
                        % Simple fit proxy variables
                        means_vec = squeeze(means(:,kk));
                        timepoints_interp = linspace(timepoints(npre+1),timepoints(end),50);
                        means_sp = interp1(timepoints(npre+1:end),means_vec(npre+1:end),timepoints_interp,'spline');
                        means_lin = interp1(timepoints(npre+1:end),means_vec(npre+1:end),timepoints_interp,'linear');
                        baseline(kk) = mean(means_vec(1:npre));
                        firstpost(kk) = means_vec(npre+1);
                        halfrecov = (firstpost(kk)-baseline(kk))/2 + baseline(kk);
                        [~,indsp] = min(abs(means_sp-halfrecov));
                        [~,indlin] = min(abs(means_lin-halfrecov));
                        Tt50sp(kk) = timepoints_interp(indsp);
                        Tt50lin(kk) = timepoints_interp(indlin);
                    end
                    warning('on','stats:nlinfit:IllConditionedJacobian');
                    
                    if ~skip_fit
                        app.fit.glo.pars = a;
                        app.fit.glo.res = r;
                        app.fit.glo.J = J;
                        app.fit.glo.cov = cov;
                        app.fit.glo.mse = mse;
                        app.fit.glo.confidence_intervals95 = ci95;
                        app.fit.glo.confidence_intervals90 = ci90;
                        app.fit.glo.fitdata = fitdata;
                        % NW temp
                        if isfield(app.params,'myfun_2') && isfield(app.params,'par0_2') && isfield(app.params,'parnames_2')
                            app.fit.glo.pars_2 = a2;
                            app.fit.glo.res_2 = r2;
                            app.fit.glo.J_2 = J2;
                            app.fit.glo.cov_2 = cov2;
                            app.fit.glo.mse_2 = mse2;
                            app.fit.glo.confidence_intervals95_2 = ci95_2;
                            app.fit.glo.confidence_intervals90_2 = ci90_2;
                            app.fit.glo.fitdata_2 = fitdata2;
                        end
                    end
                    app.fit.glo.means = means;
                    app.fit.glo.stds = stds;
                    if isfield(app.params,'roithresh')
                        app.post.gloroith = roipostth;
                        for kk=1:nthr
                            temp = roipostth(:,:,kk);
                            app.fit.roipix.postglo(kk) = sum(temp(:));
                        end
                    end
                    app.fit.glo.simplepars.baseline = baseline;
                    app.fit.glo.simplepars.firstpost = firstpost;
                    app.fit.glo.simplepars.increase = firstpost-baseline;
                    app.fit.glo.simplepars.Tt50.spline = Tt50sp;
                    app.fit.glo.simplepars.Tt50.linear = Tt50lin;
                end
                
                app.txtDisp.Value = 'DONE';
                
                
            catch errorObj
                 fclose all
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butClear
        function butClearButtonPushed(app, event)
            disp('CLEARED')
            app.pre = [];
            app.post = [];
            app.handle = [];
            app.params = [];
            app.fit = [];
            app.txtDisp.Value = 'CLEARED';
            app.mainDir = app.txtMainDir.Value;
            %             app.params.filename = getParams(app);
            getParams(app);
            app.figExerciseCEST.Visible = 'off';
            app.figExerciseCEST.Visible = 'on';
        end

        % Button pushed function: butExport
        function butExportButtonPushed(app, event)
            try
                
                app.txtDisp.Value = 'Exporting data...';
                if isfield(app.handle,'savename') && ~exist([app.handle.savename '.csv'],'file')
                    savename = app.handle.savename;
                else
                    fname = [app.mainDir filesep 'CESTdata'];
                    savename = [fname date];
                    ii=0;
                    while exist([savename '.csv'],'file')
                        ii=ii+1;
                        savename = [fname date '_' num2str(ii)];
                    end
                end
                fid = fopen([savename '.csv'],'w');
                if ~isequal(fid,-1)
                    if app.fit.skip_fit
                        exportNoFit(fid,app);
                    else
                        exportFit(fid,app)
                    end
                    app.handle.savename = savename;
                    app.txtDisp.Value = 'EXPORTED';
                else
                    app.txtDisp.Value = 'Could not export csv...';
                end
                
            catch errorObj
                fclose all
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butLoad
        function butLoadButtonPushed(app, event)
            try
                
                if exist('/media/cmroi/2A3E6A7F79133A73','dir')
                    [fname,path] = uigetfile('/media/cmroi/2A3E6A7F79133A73/*.mat','Choose a previously-saved .mat file'); % 140GB hard drive
                else
                    [fname,path] = uigetfile('*.mat','Choose a previously-saved .mat file');
                end
                if fname
                    app.txtDisp.Value = 'Loading data...';
                    disp(fullfile(path,fname))
                    in = load(fullfile(path,fname),'-mat');
                    % anonymize
                    in.out.pre.refdicomhdr = anonymizeDicomHdr(in.out.pre.refdicomhdr);
                    in.out.post.refdicomhdr = anonymizeDicomHdr(in.out.post.refdicomhdr);
                    app.mainDir = in.out.mainDir;
                    app.subdirs = in.out.subdirs;
                    app.pre = in.out.pre;
                    app.post = in.out.post;
                    app.fit = in.out.fit;
                    %                     app.params = in.out.params;
                    app.chkWassr.Value = in.out.par.iswassr;
                    app.chkFilter.Value = in.out.par.isfilter;
                    app.chkRegister.Value = in.out.par.isregister;
                    app.txtMainDir.Value = app.mainDir;
                    setNormalization(app,in.out.par.norm)
                    setDisplays(app)
                    app.txtDisp.Value = 'DONE';
                end
                
                app.figExerciseCEST.Visible = 'off';
                app.figExerciseCEST.Visible = 'on';
                
            catch errorObj
                fclose all
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butParams
        function butParamsButtonPushed(app, event)
            if anyisfield(app.pre,'refdicomhdr.OperatorName.FamilyName')
                operator = app.pre.refdicomhdr.OperatorName.FamilyName;
                str = ['Operator was: ' operator];
                msgbox(str);
            end
            if isprop(app,'params')
                snames = fieldnames(app.params);
                svalues = struct2cell(app.params);
                valtype = zeros(1,length(snames));
                for ii=1:length(snames)
                    if isa(svalues{ii},'function_handle')
                        lstr{ii} = func2str(svalues{ii});
                        valtype(ii) = 1;
                    elseif iscell(svalues{ii})
                        temp = svalues{ii};
                        temp2 = temp{1};
                        for jj=2:length(svalues{ii})
                            temp2 = [temp2 ', ' temp{jj}];
                        end
                        lstr{ii} = temp2;
                        valtype(ii) = 2;
                    else % number
                        lstr{ii} = num2str(col(svalues{ii}')');
                        valtype(ii) = 0;
                    end
                end
                newparams = inputdlg(snames,'Parameters',1,lstr,'on');
                if ~isempty(newparams)
                    for ii=1:length(snames)
                        if valtype(ii)==1
                            app.params = setfield(app.params,snames{ii},str2func(newparams{ii}));
                        elseif valtype(ii)==2
                            temp = textscan(newparams{ii},'%s','Delimiter',',');
                            app.params = setfield(app.params,snames{ii},temp{1}');
                        else
                            app.params = setfield(app.params,snames{ii},str2num(newparams{ii}));
                        end
                    end
                    if isfield(app.params,'colors')
                        app.params.colors = reshape(app.params.colors,3,[])';
                    end
                end
            end
            app.figExerciseCEST.Visible = 'off';
            app.figExerciseCEST.Visible = 'on';
        end

        % Button pushed function: butROI
        function butROIButtonPushed(app, event)
            try
                
                if ~isfield(app.pre,'refimageb') || ~isfield(app.post,'refimageb')
                    warndlg(' No reference image found. Please CALCULATE first');
                elseif ~isfield(app.pre,'rois') || ~isfield(app.post,'rois')
                    warndlg(' No ROIs found. Please Choose ROIs first')
                else
                    refpre = app.pre.refimageb .* app.pre.roimask;
                    refpost = app.post.refimageb .* app.post.roimask;
                    
                    h = figure('Name','References/ROIs','NumberTitle','off'); ax = axes(h);
                    refs = cat(2,refpre,refpost);
                    rois = cat(2,app.pre.rois,app.post.rois);
                    
                    NWimoverlay(cat(3,refs,rois),0.5,ax,app.params.colors);
                    colormap('bone')
                    axis off
                    axis equal
                    
                    b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
                        'callback',@printax,'string','Print','fontweight','bold');
                    app.handle.ref = h;
                end
                
            catch errorObj
                fclose all
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'ROIs','png','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fullfile(app.mainDir,fname),form,res,rend,'-noui')
                end
            end
        end

        % Button pushed function: butROIrecover
        function butROIrecoverButtonPushed(app, event)
            try
                sel_fit = 1;
                if ~app.fit.skip_fit && isfield(app.params,'myfun_2') && isfield(app.params,'par0_2') && isfield(app.params,'parnames_2')
                    app.fit.nfits = 2;
                    [sel_fit,ok] = listdlg('PromptString','Fit Model:','SelectionMode','single','ListString',{'1','2'});
                    if ~ok, sel_fit = 1; end
                end
                if ~app.fit.skip_fit && app.fit.nweights>1
                    [sel_weight,ok] = listdlg('PromptString','Choose a weighting:','SelectionMode','single','ListString',app.params.weights);
                    if ~ok, sel_weight = 1; end
                else
                    sel_weight = 1;
                end
                if app.fit.nthr>1 %isfield(app.params,'roithresh')
                    if app.fit.nthr_abs>1
                        if app.fit.nthr_per>1
                            str = cellstr(num2str([app.params.roithresh app.params.roithreshpercent]'))';
                        else
                            str = cellstr(num2str(app.params.roithresh'))';
                        end
                    elseif app.fit.nthr_per>1
                        str = cellstr(num2str(app.params.roithreshpercent'))';
                    end
                    [selection,ok] = listdlg('PromptString','Choose a threshold:','SelectionMode','single','ListString',str);
                    if ~ok, selection = 1; end
                    
                    if isfield(app.fit,'glo')
                        gloroipostth = app.post.gloroith;
                        cestpost = app.post.CESTmap;
                        cestpre = app.pre.CESTmap;
                        bins = [];
                        
                        cestpremean = squeeze(mean(cestpre,3));
                        cestpoint = cestpost(:,:,1); % first time point
                        glocestpremeanroith = mean(col(cestpremean(gloroipostth(:,:,selection)>0)));
                        glocestpostroith = col(cestpoint(gloroipostth(:,:,selection)>0)) - glocestpremeanroith;
                        dispROIhist(glocestpostroith,bins,'Global');
                        
                        glocestpostroith2 = col(cestpoint(gloroipostth(:,:,selection)>0)-cestpremean(gloroipostth(:,:,selection)>0));
                        dispROIhist(glocestpostroith2,bins,'Global');
                        
                        if app.fit.skip_fit
                            NWplotROIrecovery(app.fit.timepoints,[],app.fit.glo.means(:,selection).',app.fit.glo.stds(:,selection).',app.post.refimageb.*app.post.roimask,app.post.gloroith(:,:,selection),[],app);
                        else
                            if sel_fit==1
                                NWplotROIrecovery(app.fit.timepoints,app.fit.glo.fitdata(:,sel_weight,selection),app.fit.glo.means(:,selection).',app.fit.glo.stds(:,selection).',app.post.refimageb.*app.post.roimask,app.post.gloroith(:,:,selection),[],app);
                            elseif sel_fit==2
                                NWplotROIrecovery(app.fit.timepoints,app.fit.glo.fitdata_2(:,sel_weight,selection),app.fit.glo.means(:,selection).',app.fit.glo.stds(:,selection).',app.post.refimageb.*app.post.roimask,app.post.gloroith(:,:,selection),[],app);
                            end
                            printROIstats(app,sel_weight,selection,sel_fit,true)
                        end
                    end
                    
                    roipostth = app.post.roisth;
                    cestpost = app.post.CESTmap;
                    cestpre = app.pre.CESTmap;
                    means = app.fit.means;
                    npre = size(cestpre,3);
                    nthr = app.fit.nthr;
                    nrois = app.fit.nrois;
                    cest = cat(3,cestpre,cestpost);
                    roinames = app.params.roinames;
                    bins = [];
                    
                    cestpremean = squeeze(mean(cestpre,3));
                    cestpoint = cestpost(:,:,1); % first time point
                    for jj=1:nrois
                        cestpremeanroith(jj) = mean(col(cestpremean(roipostth(:,:,jj,selection)>0)));
                        cestpostroith{jj} = col(cestpoint(roipostth(:,:,jj,selection)>0)) - cestpremeanroith(jj);
                        cestpostroith2{jj} = col(cestpoint(roipostth(:,:,jj,selection)>0)-cestpremean(roipostth(:,:,jj,selection)>0));
                    end
                    dispROIhist(cestpostroith,bins,roinames);
                    dispROIhist(cestpostroith2,bins,roinames);
                    
                    if app.fit.skip_fit
                        app.params.colors = NWplotROIrecovery(app.fit.timepoints,[],app.fit.means(:,:,selection),app.fit.stds(:,:,selection),app.post.refimageb.*app.post.roimask,app.post.roisth(:,:,:,selection),app.params.colors,app);
                    else
                        if sel_fit==1
                            app.params.colors = NWplotROIrecovery(app.fit.timepoints,app.fit.fitdata(:,:,sel_weight,selection),app.fit.means(:,:,selection),app.fit.stds(:,:,selection),app.post.refimageb.*app.post.roimask,app.post.roisth(:,:,:,selection),app.params.colors,app);
                        elseif sel_fit==2
                            app.params.colors = NWplotROIrecovery(app.fit.timepoints,app.fit.fitdata_2(:,:,sel_weight,selection),app.fit.means(:,:,selection),app.fit.stds(:,:,selection),app.post.refimageb.*app.post.roimask,app.post.roisth(:,:,:,selection),app.params.colors,app);
                        end
                        printROIstats(app,sel_weight,selection,sel_fit,false)
                    end
                    
                else
                    if isfield(app.fit,'glo')
                        if app.fit.skip_fit
                            NWplotROIrecovery(app.fit.timepoints,[],app.fit.glo.means,app.fit.glo.stds,app.post.refimageb.*app.post.roimask,app.post.gloroi,[],app);
                        else
                            if sel_fit==1
                                NWplotROIrecovery(app.fit.timepoints,app.fit.glo.fitdata(:,:,sel_weight),app.fit.glo.means,app.fit.glo.stds,app.post.refimageb.*app.post.roimask,app.post.gloroi,[],app);
                            elseif sel_fit==2
                                NWplotROIrecovery(app.fit.timepoints,app.fit.glo.fitdata_2(:,:,sel_weight),app.fit.glo.means,app.fit.glo.stds,app.post.refimageb.*app.post.roimask,app.post.gloroi,[],app);
                            end
                            printROIstats(app,sel_weight,1,sel_fit,true)
                        end
                    end
                    
                    if app.fit.skip_fit
                        app.params.colors = NWplotROIrecovery(app.fit.timepoints,[],app.fit.means,app.fit.stds,app.post.refimageb.*app.post.roimask,app.post.rois,app.params.colors,app);
                    else
                        if sel_fit==1
                            app.params.colors = NWplotROIrecovery(app.fit.timepoints,app.fit.fitdata(:,:,sel_weight),app.fit.means,app.fit.stds,app.post.refimageb.*app.post.roimask,app.post.rois,app.params.colors,app);
                        elseif sel_fit==2
                            app.params.colors = NWplotROIrecovery(app.fit.timepoints,app.fit.fitdata_2(:,:,sel_weight),app.fit.means,app.fit.stds,app.post.refimageb.*app.post.roimask,app.post.rois,app.params.colors,app);
                        end
                        printROIstats(app,sel_weight,1,sel_fit,false)
                    end
                end
                
            catch errorObj
                fclose all
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butSave
        function butSaveButtonPushed(app, event)
            try
                getSubjectNo = false;
                if getSubjectNo
                    SubjectNo = inputdlg('Enter Subject Number: ','Subject No.');
                    if ~isempty(SubjectNo)
                        app.params.SubjectNo = str2num(SubjectNo{1});
                    end
                end
                addNote = true;
                if addNote
                    note = inputdlg('Enter note: ','Note');
                    if ~isempty(note)
                        app.params.notes = note;
                    end
                end
                
                app.txtDisp.Value = 'Saving data...';
                clear out
                fname = [app.mainDir filesep 'CESTdata'];
                ii=0;
                savename = [fname date];
                while exist([savename '.mat'],'file')
                    ii=ii+1;
                    savename = [fname date '_' num2str(ii)];
                end
                app.handle.savename = savename;
                out.mainDir = app.mainDir;
                out.subdirs = app.subdirs;
                out.par.iswassr = app.chkWassr.Value;
                out.par.isfilter = app.chkFilter.Value;
                out.par.isregister = app.chkRegister.Value;
                out.par.norm = getNormalization(app);
                out.pre = app.pre;
                out.post = app.post;
                out.fit = app.fit;
                out.params = app.params;
                save([savename '.mat'],'out');
                app.txtDisp.Value = 'SAVED';
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: chkWassr
        function chkWassrValueChanged(app, event)
            setDisplays(app);
        end

        % Close request function: figExerciseCEST
        function figExerciseCESTCloseRequest(app, event)
            rmpath('common');
            diary off
            delete(app);
        end

        % Value changed function: txtB0Post
        function txtB0PostValueChanged(app, event)
            value = app.txtB0Post.Value;
            if app.chkWassr.Value == true
                app.subdirs.WASSRpost = value;
            else
                % fill in the details
            end
        end

        % Value changed function: txtB0Pre
        function txtB0PreValueChanged(app, event)
            value = app.txtB0Pre.Value;
            if app.chkWassr.Value == true
                app.subdirs.WASSRpre = value;
            else
                % fill in the details
            end
        end

        % Value changed function: txtB1Post
        function txtB1PostValueChanged(app, event)
            value = app.txtB1Post.Value;
            app.subdirs.B1post = value;
        end

        % Value changed function: txtB1Pre
        function txtB1PreValueChanged(app, event)
            value = app.txtB1Pre.Value;
            app.subdirs.B1pre = value;
        end

        % Value changed function: txtCESTPost
        function txtCESTPostValueChanged(app, event)
            value = app.txtCESTPost.Value;
            app.subdirs.CESTpost = value;
        end

        % Value changed function: txtCESTPre
        function txtCESTPreValueChanged(app, event)
            value = app.txtCESTPre.Value;
            app.subdirs.CESTpre = value;
        end

        % Value changed function: txtMainDir
        function txtMainDirValueChanged(app, event)
            app.mainDir = app.txtMainDir.Value;
        end

        % Value changed function: txtRefPost
        function txtRefPostValueChanged(app, event)
            value = app.txtRefPost.Value;
            app.subdirs.Refpost = value;
        end

        % Value changed function: txtRefPre
        function txtRefPreValueChanged(app, event)
            value = app.txtRefPre.Value;
            app.subdirs.Refpre = value;
        end

        % Button pushed function: butExcTimePoints
        function butExcTimePointsButtonPushed(app, event)
            prompt = {sprintf('Enter indices of scans to exclude from fitting separated by commas.\nIndex counter starts at 1 with pre-exercise. ("0" or empty to keep all time points) ')};
            dlgtitle = 'Excluded timepoints';
            fieldSize = [1 80];
            definput = {'0'};
            tp = inputdlg(prompt,dlgtitle,fieldSize,definput);
            if isempty(tp) | str2num(tp{1})==0
                excludedTimes = [];
            else
                excludedTimes = str2num(tp{1});
            end
            app.fit.excludedTimePoints = excludedTimes;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figExerciseCEST = uifigure('Visible', 'off');
            app.figExerciseCEST.Name = 'Exercise CEST';
            app.figExerciseCEST.CloseRequestFcn = createCallbackFcn(app, @figExerciseCESTCloseRequest, true);
            app.NormalizationGroup = uibuttongroup(app.figExerciseCEST);
            app.NormalizationGroup.Title = 'Normalization';
            app.radNeg = uiradiobutton(app.NormalizationGroup);
            app.radNeg.Text = 'M-';
            app.radNeg.Value = true;
            app.radM0 = uiradiobutton(app.NormalizationGroup);
            app.radM0.Enable = 'off';
            app.radM0.Text = 'M0';
            app.radMT = uiradiobutton(app.NormalizationGroup);
            app.radMT.Enable = 'off';
            app.radMT.Text = 'MT';
            app.LabelEditField = uilabel(app.figExerciseCEST);
            app.LabelEditField.Text = 'Main Directory';
            app.txtMainDir = uieditfield(app.figExerciseCEST, 'text');
            app.txtMainDir.ValueChangedFcn = createCallbackFcn(app, @txtMainDirValueChanged, true);
            app.txtMainDir.Editable = 'off';
            app.butChangeDir = uibutton(app.figExerciseCEST, 'push');
            app.butChangeDir.ButtonPushedFcn = createCallbackFcn(app, @ChangeDir, true);
            app.butChangeDir.Text = 'Change Dir';
            app.butChooseROI = uibutton(app.figExerciseCEST, 'push');
            app.butChooseROI.ButtonPushedFcn = createCallbackFcn(app, @butChooseROIButtonPushed, true);
            app.butChooseROI.Text = 'ROIs';
            app.butReadCalc = uibutton(app.figExerciseCEST, 'push');
            app.butReadCalc.ButtonPushedFcn = createCallbackFcn(app, @butReadCalcButtonPushed, true);
            app.butReadCalc.Text = 'READ/CALC';
            app.DisplayPanel = uipanel(app.figExerciseCEST);
            app.DisplayPanel.Title = 'Display';
            app.butB0map = uibutton(app.DisplayPanel, 'push');
            app.butB0map.ButtonPushedFcn = createCallbackFcn(app, @butB0mapButtonPushed, true);
            app.butB0map.Text = 'B0map';
            app.butCESTw_im = uibutton(app.DisplayPanel, 'push');
            app.butCESTw_im.ButtonPushedFcn = createCallbackFcn(app, @butCESTw_imButtonPushed, true);
            app.butCESTw_im.Text = 'CESTw images';
            app.butB1map = uibutton(app.DisplayPanel, 'push');
            app.butB1map.ButtonPushedFcn = createCallbackFcn(app, @butB1mapButtonPushed, true);
            app.butB1map.Text = 'B1map';
            app.butROI = uibutton(app.DisplayPanel, 'push');
            app.butROI.ButtonPushedFcn = createCallbackFcn(app, @butROIButtonPushed, true);
            app.butROI.Text = 'Ref/ROIs';
            app.butROIrecover = uibutton(app.DisplayPanel, 'push');
            app.butROIrecover.ButtonPushedFcn = createCallbackFcn(app, @butROIrecoverButtonPushed, true);
            app.butROIrecover.Text = 'ROI time series';
            app.butCESTmap = uibutton(app.DisplayPanel, 'push');
            app.butCESTmap.ButtonPushedFcn = createCallbackFcn(app, @butCESTmapButtonPushed, true);
            app.butCESTmap.Text = 'CESTmaps';
            app.preExPanel = uipanel(app.figExerciseCEST);
            app.preExPanel.Title = 'Directories/Dicoms - PRE';
            app.Label2 = uilabel(app.preExPanel);
            app.Label2.Text = 'B1';
            app.txtB1Pre = uieditfield(app.preExPanel, 'text');
            app.txtB1Pre.ValueChangedFcn = createCallbackFcn(app, @txtB1PreValueChanged, true);
            app.Label3 = uilabel(app.preExPanel);
            app.Label3.Text = 'CEST';
            app.txtCESTPre = uieditfield(app.preExPanel, 'text');
            app.txtCESTPre.ValueChangedFcn = createCallbackFcn(app, @txtCESTPreValueChanged, true);
            app.Label8 = uilabel(app.preExPanel);
            app.Label8.Text = 'B0';
            app.txtB0Pre = uieditfield(app.preExPanel, 'text');
            app.txtB0Pre.ValueChangedFcn = createCallbackFcn(app, @txtB0PreValueChanged, true);
            app.Label9 = uilabel(app.preExPanel);
            app.Label9.Text = 'Reference';
            app.txtRefPre = uieditfield(app.preExPanel, 'text');
            app.txtRefPre.ValueChangedFcn = createCallbackFcn(app, @txtRefPreValueChanged, true);
            app.postExPanel = uipanel(app.figExerciseCEST);
            app.postExPanel.Title = 'Directories/Dicoms - POST';
            app.Label4 = uilabel(app.postExPanel);
            app.Label4.Text = 'Reference';
            app.txtRefPost = uieditfield(app.postExPanel, 'text');
            app.txtRefPost.ValueChangedFcn = createCallbackFcn(app, @txtRefPostValueChanged, true);
            app.Label5 = uilabel(app.postExPanel);
            app.Label5.Text = 'B0';
            app.txtB0Post = uieditfield(app.postExPanel, 'text');
            app.txtB0Post.ValueChangedFcn = createCallbackFcn(app, @txtB0PostValueChanged, true);
            app.Label6 = uilabel(app.postExPanel);
            app.Label6.Text = 'B1';
            app.txtB1Post = uieditfield(app.postExPanel, 'text');
            app.txtB1Post.ValueChangedFcn = createCallbackFcn(app, @txtB1PostValueChanged, true);
            app.Label7 = uilabel(app.postExPanel);
            app.Label7.Text = 'CEST';
            app.txtCESTPost = uieditfield(app.postExPanel, 'text');
            app.txtCESTPost.ValueChangedFcn = createCallbackFcn(app, @txtCESTPostValueChanged, true);
            app.LabelDropDown = uilabel(app.figExerciseCEST);
            app.LabelDropDown.Text = 'Change Dir';
            app.ChangeDirDropDown = uidropdown(app.figExerciseCEST);
            app.ChangeDirDropDown.Items = {'Ref Pre', 'B0 Pre', 'B1 Pre', 'CEST Pre', 'Ref Post', 'B0 Post', 'B1 Post', 'CEST Post', ''};
            app.ChangeDirDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeDirDropDownValueChanged, true);
            app.ChangeDirDropDown.Value = '';
            app.chkWassr = uicheckbox(app.figExerciseCEST);
            app.chkWassr.ValueChangedFcn = createCallbackFcn(app, @chkWassrValueChanged, true);
            app.chkWassr.Text = 'WASSR';
            app.chkWassr.Value = true;
            app.LabelEditField2 = uilabel(app.figExerciseCEST);
            app.LabelEditField2.Text = '';
            app.txtDisp = uieditfield(app.figExerciseCEST, 'text');
            app.txtDisp.Editable = 'off';
            app.butSave = uibutton(app.figExerciseCEST, 'push');
            app.butSave.ButtonPushedFcn = createCallbackFcn(app, @butSaveButtonPushed, true);
            app.butSave.Text = 'Save';
            app.butLoad = uibutton(app.figExerciseCEST, 'push');
            app.butLoad.ButtonPushedFcn = createCallbackFcn(app, @butLoadButtonPushed, true);
            app.butLoad.Text = 'Load';
            app.LabelNumericEditField3 = uilabel(app.figExerciseCEST);
            app.LabelNumericEditField3.Text = 'No.';
            app.numROIs = uieditfield(app.figExerciseCEST, 'numeric');
            app.numROIs.Limits = [0 9];
            app.numROIs.ValueDisplayFormat = '%.0f';
            app.numROIs.Value = 1;
            app.chkFilter = uicheckbox(app.figExerciseCEST);
            app.chkFilter.Text = 'Filter';
            app.chkFilter.Value = true;
            app.butExport = uibutton(app.figExerciseCEST, 'push');
            app.butExport.ButtonPushedFcn = createCallbackFcn(app, @butExportButtonPushed, true);
            app.butExport.Text = 'Export';
            app.butClear = uibutton(app.figExerciseCEST, 'push');
            app.butClear.ButtonPushedFcn = createCallbackFcn(app, @butClearButtonPushed, true);
            app.butClear.Text = 'Clear';
            app.chkRegister = uicheckbox(app.figExerciseCEST);
            app.chkRegister.Enable = 'off';
            app.chkRegister.Text = 'Register';
            app.butParams = uibutton(app.figExerciseCEST, 'push');
            app.butParams.ButtonPushedFcn = createCallbackFcn(app, @butParamsButtonPushed, true);
            app.butParams.Text = 'Params';
            app.butExcTimePoints = uibutton(app.figExerciseCEST, 'push');
            app.butExcTimePoints.ButtonPushedFcn = createCallbackFcn(app, @butExcTimePointsButtonPushed, true);
            app.butExcTimePoints.Text = {'Exclude'; 'Timepoints'};
            % Show the figure after all components are created
            app.figExerciseCEST.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExerciseCESTv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figExerciseCEST)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figExerciseCEST)
        end
    end
end